classdef CeldaLeme < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CeldaCargaFigure            matlab.ui.Figure
        btn_tomarMuestra            matlab.ui.control.Button
        btn_doTara                  matlab.ui.control.Button
        lamp_reading                matlab.ui.control.Lamp
        medidor_KG                  matlab.ui.control.Gauge
        lbl_valFuerzaKg             matlab.ui.control.Label
        FuerzaLabel                 matlab.ui.control.Label
        btn_imgHelp                 matlab.ui.control.Image
        img_Logo                    matlab.ui.control.Image
        tab_TipoConexion            matlab.ui.container.TabGroup
        ConexionSerialTab           matlab.ui.container.Tab
        dd_serialPort               matlab.ui.control.DropDown
        lbl_serialPort              matlab.ui.control.Label
        btn_UpdateSerialPort        matlab.ui.control.Button
        ef_serialBPS                matlab.ui.control.NumericEditField
        lbl_serialBPS               matlab.ui.control.Label
        btn_serialConnect           matlab.ui.control.Button
        tab_Ajustes                 matlab.ui.container.TabGroup
        tab_AjusteCalibracion       matlab.ui.container.Tab
        ef_valCalManual             matlab.ui.control.NumericEditField
        ValordeCalibracinEditFieldLabel  matlab.ui.control.Label
        btn_readValPesoConocido     matlab.ui.control.Button
        btn_readValCalManual        matlab.ui.control.Button
        btn_doCalManual             matlab.ui.control.Button
        ef_valPesoConocido          matlab.ui.control.NumericEditField
        PesoConocidokgLabel         matlab.ui.control.Label
        btn_uploadPesoConocido      matlab.ui.control.Button
        btn_doCalAuto               matlab.ui.control.Button
        CalibracionAutomticaLabel   matlab.ui.control.Label
        CalibracinoManualLabel      matlab.ui.control.Label
        btn_abrirArchivoMAT         matlab.ui.control.Button
        btn_ExportMAT               matlab.ui.control.Button
        btn_ExportCSV               matlab.ui.control.Button
        lbl_NombreArchivoTextoFijo  matlab.ui.control.Label
        lbl_nombreArchivo           matlab.ui.control.Label
        btn_initRecord              matlab.ui.control.Button
        btn_stopRecord              matlab.ui.control.Button
        graph_FuerzaKg              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        config;
        serial;
        buttons_lock;
        reading;
        raw;
        vect_time;
        vect_kg;
    end


    methods (Access = private)

        function manageStateOfButtons(app, connect)
            if connect
                % Conexión Serial (Lo deshabilito)
                app.dd_serialPort.Enable = 'off';
                app.ef_serialBPS.Enable = 'off';
                app.btn_UpdateSerialPort.Enable = 'off';
                
                % Box: Ajustes
                app.ef_valCalManual.Enable = 'on';
                app.btn_readValCalManual.Enable = 'on';
                app.btn_doCalManual.Enable = 'on';
    
                app.ef_valPesoConocido.Enable = 'on';
                app.btn_readValPesoConocido.Enable = 'on';
                app.btn_uploadPesoConocido.Enable = 'on';
                app.btn_doCalAuto.Enable = 'on';

                % Botones del Registro de datos
                app.btn_tomarMuestra.Enable = 'on';
                app.btn_initRecord.Enable = 'on';
                app.btn_stopRecord.Enable = 'on';
    
                % Box: Tara
                app.btn_doTara.Enable = 'on';
    
                % Botones de export/import
                app.btn_abrirArchivoMAT.Enable = 'off';
                app.btn_ExportMAT.Enable = 'on';
                app.btn_ExportCSV.Enable = 'on';
                app.lbl_NombreArchivoTextoFijo.Visible = "off";
                app.lbl_nombreArchivo.Visible = "off";
            else
                % Conexión Serial (Lo deshabilito)
                app.dd_serialPort.Enable = 'on';
                app.ef_serialBPS.Enable = 'on';
                app.btn_UpdateSerialPort.Enable = 'on';
                
                % Box: Ajustes
                app.ef_valCalManual.Enable = 'off';
                app.btn_readValCalManual.Enable = 'off';
                app.btn_doCalManual.Enable = 'off';
    
                app.ef_valPesoConocido.Enable = 'off';
                app.btn_readValPesoConocido.Enable = 'off';
                app.btn_uploadPesoConocido.Enable = 'off';
                app.btn_doCalAuto.Enable = 'off';

                % Botones del Registro de datos
                app.btn_tomarMuestra.Enable = 'off';
                app.btn_initRecord.Enable = 'off';
                app.btn_stopRecord.Enable = 'off';
    
                % Box: Tara
                app.btn_doTara.Enable = 'off';
    
                % Botones de export/import
                app.btn_abrirArchivoMAT.Enable = 'on';
                app.btn_ExportMAT.Enable = 'on';
                app.btn_ExportCSV.Enable = 'on';
                app.lbl_NombreArchivoTextoFijo.Visible = "off";
                app.lbl_nombreArchivo.Visible = "off";
            end
        end
        
        function disableButtonsInRecord(app) 
            % Box: Ajustes
            app.ef_valCalManual.Enable = 'off';
            app.btn_readValCalManual.Enable = 'off';
            app.btn_doCalManual.Enable = 'off';

            app.ef_valPesoConocido.Enable = 'off';
            app.btn_readValPesoConocido.Enable = 'off';
            app.btn_uploadPesoConocido.Enable = 'off';
            app.btn_doCalAuto.Enable = 'off';

            app.btn_tomarMuestra.Enable = 'off';

            % Box: Tara
            app.btn_doTara.Enable = 'off';

            % Botones de export/import
            % app.btn_abrirArchivoMAT.Enable = 'off';
            % app.btn_ExportMAT.Enable = 'off';
            % app.btn_ExportCSV.Enable = 'off';
        end

        function enableButtonsInRecord(app) 
            % Box: Ajustes
            app.ef_valCalManual.Enable = 'on';
            app.btn_readValCalManual.Enable = 'on';
            app.btn_doCalManual.Enable = 'on';

            app.ef_valPesoConocido.Enable = 'on';
            app.btn_readValPesoConocido.Enable = 'on';
            app.btn_uploadPesoConocido.Enable = 'on';
            app.btn_doCalAuto.Enable = 'on';

            app.btn_tomarMuestra.Enable = 'on';

            % Box: Tara
            app.btn_doTara.Enable = 'on';

            % Botones de export/import
            % app.btn_abrirArchivoMAT.Enable = 'on';
            % app.btn_ExportMAT.Enable = 'on';
            % app.btn_ExportCSV.Enable = 'on';
        end

        function serialCallback(app, src, ~)
            if(app.reading == 1)
                try
                    raw = utilidades.processRawData(readline(src), 'kg'); %#ok<*ADPROPLC>
                    disp(raw);
                    if isnan(raw)
                        error("No se logro identificar el valor recibido!");
                    end
                    
                    % Etiqueta y Medidor
                    app.lbl_valFuerzaKg.Text = string(raw) + " kg";
                    app.medidor_KG.Value = raw;
                    
                    % Creo el vector y lo grafico
                    app.vect_time = [app.vect_time, toc];
                    app.vect_kg = [app.vect_kg, raw];
                    plot(app.graph_FuerzaKg, app.vect_time, app.vect_kg);
                catch ME
                    disp("[SerialCallBack]: " + ME.message);
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onLoad(app)
            % ───────────────────────────────────────────────────────────────
            %                        SETUP de la App
            % ───────────────────────────────────────────────────────────────
            % Variables de configuraciones
            app.config.author = "Christian Yoel Herrera";
            app.config.version = 1.0;
            app.config.id = "20250515";
            app.config.timeout_serial = 5;
            app.config.timeout_cal_auto = 30;
            % ──────────────────────────────────────────
            % Demas variables
            app.buttons_lock = 1;
            app.reading = 0;
            manageStateOfButtons(app, false);
            app.dd_serialPort.Items = serialportlist;
            % ──────────────────────────────────────────
        end

        % Button pushed function: btn_serialConnect
        function Serial_Connect(app, event)
            if(strcmp(app.btn_serialConnect.Text, "Conectar"))
                % ───────────────────────────────────────────────────────────────
                %                Realiza la conexion con el Arduino
                % ───────────────────────────────────────────────────────────────
                d = uiprogressdlg(app.CeldaCargaFigure, Message="Abriendo el puerto COM...", Title="Conectando...", ShowPercentage="on");
                app.reading = 0;
                
                try
                    % Realizo la conexion
                    app.serial.connection = serialport(app.dd_serialPort.Value, app.ef_serialBPS.Value, "Timeout", app.config.timeout_serial);
                    configureTerminator(app.serial.connection, "CR");
                    configureCallback(app.serial.connection, "terminator", @(src, evt)serialCallback(app, src, evt));


                    % ==> Limpio los Buffers
                    % Previene que existan datos en el buffer ya que en este punto, el arduino
                    % se reinicia al abrirse la conexión.
                    % ─────────────────────> Mensaje del uiprogress
                    d.Value = 0.2;
                    d.Message = "Limpiando buffers...";
                    % ─────────────────────────────────────────────] 
                    pause(2);
                    flush(app.serial.connection, "input");
                    flush(app.serial.connection, "output");


                    % ==> Verifico el modelo (check -> ok)
                    % ─────────────────────> Mensaje del uiprogress
                    d.Value = 0.6;
                    d.Message = "Verificando modelo...";
                    % ─────────────────────────────────────────────]
                    if ~utilidades.checkResponseFromSerial(app.serial.connection, "#check&", "#id$" + app.config.id + "&")
                        error("No se logró identificar el modulo. Probablemente por no coincidir la version de la app con el del módulo.");
                    end
                    pause(0.3);


                    % ==> Leo las configuraciones (2 valores float)
                    % ─────────────────────> Mensaje del uiprogress
                    d.Value = 0.8;
                    d.Message = "Leyendo configuraciones...";
                    % ─────────────────────────────────────────────] 
                    pause(1);
                    cte_calib = utilidades.getParamFloat(app.serial.connection, "#get-cte-calib&", "cte-calib");
                    pause(0.1);
                    peso_conocido = utilidades.getParamFloat(app.serial.connection, "#get-weight&", "weight");
                    if isnan(cte_calib) || isnan(peso_conocido)
                        error(" = 'Ocurrió un error al descargar los datos del modulo!");
                    end
                    app.ef_valCalManual.Value = cte_calib;
                    app.ef_valPesoConocido.Value = peso_conocido;


                    % ==> Finalizo el uialert, cambio el texto del boton y habilito todos los demas.
                    % ─────────────────────> Mensaje del uiprogress
                    d.Value = 1;
                    d.Message = "Finalizando configuraciones...";
                    pause(0.2);
                    uialert(app.CeldaCargaFigure, "Conexión Establecida", "Conexion", Icon='success');
                    % ─────────────────────────────────────────────]  
                    
                    app.btn_serialConnect.Text = "Desconectar";
                    app.manageStateOfButtons(true)
                    
                catch ME
                    app.serial.connection = [];     %Close(serial)
                    app.manageStateOfButtons(false);
                    switch(ME.identifier)
                        case 'transportclients:string:timeoutToken'
                            uialert(app.CeldaCargaFigure, 'No se recibio respuesta del modulo', 'Error', Icon='error');
                        case 'serialport:serialport:ConnectionFailed'
                            uialert(app.CeldaCargaFigure, 'El puerto se encuentra en uso por otra aplicacion', 'Error', Icon='error');
                        otherwise
                            uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon='error');
                    end
                end

                close(d);
            else
                % ───────────────────────────────────────────────────────────────
                %        Desconecta la comunicacion serial y limpia
                % ───────────────────────────────────────────────────────────────
                app.reading = 0;
                app.btn_serialConnect.Text = "Conectar";
                app.serial.connection = []; %Close(Serial)
                app.manageStateOfButtons(false);
            end
        end

        % Button pushed function: btn_ExportMAT
        function Export_MAT(app, event)
            % ───────────────────────────────────────────────────────────────
            %                   Exporta el archivo .MAT
            % ───────────────────────────────────────────────────────────────
            t = datetime;
            t.Format = 'yyyy.MM.dd_HH.mm';

            try
                param.peso_conocido = app.ef_valPesoConocido.Value;
                param.cte_calib = app.ef_valCalManual.Value; %#ok<*STRNU>

                data_time = app.vect_time; %#ok<*NASGU>
                data_kg = app.vect_kg;

                uisave({'param', 'data_time', 'data_kg'}, string(t))
                uialert(app.CeldaCargaFigure, 'Se ha exportado el archivo .MAT', 'Exportación', Icon = 'info');
            catch ME
                switch(ME.identifier)
                    case 'MATLAB:structRefFromNonStruct'
                        uialert(app.CeldaCargaFigure, 'Se debe primero cargar alguna matriz de datos para poder exportar', 'Error', Icon='error');
                    otherwise
                        uialert(app.CeldaCargaFigure, "[" + ME.identifier + "]: " + ME.message, "Error", Icon='error');
                end
            end
        end

        % Button pushed function: btn_ExportCSV
        function Export_CSV(app, event)
            % ───────────────────────────────────────────────────────────────
            %                    Exporta el archivo .CSV
            % ───────────────────────────────────────────────────────────────
            t = datetime;
            t.Format = 'yyyy.MM.dd_HH.mm';

            data_time = ['Tiempo', replace(string(app.vect_time), '.', ',')];
            data_kg = ['KG', replace(string(app.vect_kg), '.', ',')];

            [name, path] = uiputfile('*.csv', 'Elegir ubicacion', string(t) + ".csv");
            if isequal(name, 0)
                return;
            end

            if ischar(name)
                mtx = [data_time', data_kg'];
                writematrix(mtx, fullfile(path, name), 'Delimiter', 'tab');
            end
        end

        % Button pushed function: btn_abrirArchivoMAT
        function Import_MAT(app, event)
            % ───────────────────────────────────────────────────────────────
            %    Abre el archivo .MAT y realiza la carga de los valores
            % ───────────────────────────────────────────────────────────────
            try
                [file, path] = uigetfile('*.mat');
                if ~isequal(file, 0)
                    ruta = fullfile(path, file);
                    
                    vars = who('-file', ruta);                    % Variables del archivo
                    required = {'param', 'data_time', 'data_kg'}; % Variables requeridas
                    
                    % Verifica que existan las variables requeridas.
                    if all(ismember(required, vars))
                        load(ruta, required{:});
                        uialert(app.CeldaCargaFigure, "Archivo Leido: <font color='green'>" + file + "</font>", 'Apertura de Archivo .MAT', Icon='success', Interpreter='html');
                        
                        % Muestro la leyenda con el nombre del archivo cargado.
                        app.lbl_nombreArchivo.Text = file;
                        app.lbl_NombreArchivoTextoFijo.Visible = "on";
                        app.lbl_nombreArchivo.Visible = "on";


                        % Cargo en los campos correspondientes
                        app.lbl_valFuerzaKg.Text = "0.00 kg";
                        app.medidor_KG.Value = 0;

                        app.ef_valCalManual.Value = param.cte_calib;
                        app.ef_valPesoConocido.Value = param.peso_conocido;
                        
                        % Creo el vector y lo grafico
                        app.vect_time = data_time;
                        app.vect_kg = data_kg;
                        plot(app.graph_FuerzaKg, app.vect_time, app.vect_kg);
                    else
                        error('Faltan variables en el archivo MAT');
                    end
                end
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon='error');
            end
        end

        % Button pushed function: btn_UpdateSerialPort
        function Change_Serial_BPS(app, event)
            app.dd_serialPort.Items = serialportlist;
        end

        % Image clicked function: btn_imgHelp
        function Click_Help_Window(app, event)
            Ventana_Help;
        end

        % Close request function: CeldaCargaFigure
        function Close_App(app, event)
            delete(Ventana_Help);
            delete(app)
        end

        % Image clicked function: img_Logo
        function img_LogoImageClicked(app, event)
            web('https://leme.ing.unlp.edu.ar/');
        end

        % Button pushed function: btn_initRecord
        function btn_initRecordButtonPushed(app, event)
            % ───────────────────────────────────────────────────────────────
            %   Activa en el Arduino el envío constente de valores en kg
            % ───────────────────────────────────────────────────────────────
            try
                if ~utilidades.checkResponseFromSerial(app.serial.connection, "#start&", "#starting&")
                    error("No se recibió la confirmacion de la toma de muestras!");
                end
                app.vect_time = []; app.vect_kg = [];  
                app.lamp_reading.Color = [0.00,1.00,0.00];  % Lamp en verde
                tic;
                flush(app.serial.connection, 'input');
                flush(app.serial.connection, 'output');
                app.reading = 1;
                app.disableButtonsInRecord();
            catch ME
                app.lamp_reading.Color = [0.85,0.33,0.10];  % Lamp en naranja
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_stopRecord
        function btn_stopRecordButtonPushed(app, event)
            % ───────────────────────────────────────────────────────────────
            %    Desactiva en el Arduino el envío constante de valores
            % ───────────────────────────────────────────────────────────────
            try
                app.reading = 0;
                pause(0.2);
                if ~utilidades.checkResponseFromSerial(app.serial.connection, "#stop&", "#stopping&")
                    error("No se recibió la confirmación del módulo!");
                end
                app.lamp_reading.Color = [0.85,0.33,0.10];  % Lamp en naranja
                app.enableButtonsInRecord();
                flush(app.serial.connection, 'input');
                flush(app.serial.connection, 'output');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_readValCalManual
        function btn_readValCalManualButtonPushed(app, event)
            % ───────────────────────────────────────────────────────────────
            %   Envia la peticion al arduino para leer la constante actual.
            % ───────────────────────────────────────────────────────────────
            try
                cte_calib = utilidades.getParamFloat(app.serial.connection, "#get-cte-calib&", "cte-calib");
                if isnan(cte_calib)
                    error("No se obtuvo una respuesta válida!");
                end
                
                app.ef_valCalManual.Value = cte_calib;
                uialert(app.CeldaCargaFigure, 'Se actualizó el valor correctamente!', 'Descarga', Icon = 'success');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_readValPesoConocido
        function btn_readValPesoConocidoButtonPushed(app, event)
            % ───────────────────────────────────────────────────────────────
            %   Envia la petición para obtener el valor del peso conocido
            % ───────────────────────────────────────────────────────────────
            try
                cte_calib = utilidades.getParamFloat(app.serial.connection, "#get-weight&", "weight");
                if isnan(cte_calib)
                    error("No se obtuvo una respuesta válida!");
                end
                
                app.ef_valPesoConocido.Value = cte_calib;
                uialert(app.CeldaCargaFigure, 'Se actualizó el valor correctamente!', 'Descarga', Icon = 'success');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_uploadPesoConocido
        function btn_uploadPesoConocidoButtonPushed(app, event)
            % ───────────────────────────────────────────────────────────────
            %  Envia el dato del EditField correspondiente al peso conocido
            % ───────────────────────────────────────────────────────────────
            try
                str = "#set-weight$" + string(app.ef_valPesoConocido.Value) + "&";

                if ~utilidades.checkResponseFromSerial(app.serial.connection, str, "#saved-weight&")
                    error("Ocurrió un error al cargar el parametro!");
                end
                
                uialert(app.CeldaCargaFigure, 'Peso conocido actualizado correctamente!', 'Descarga', Icon = 'success');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_doCalManual
        function btn_doCalManualButtonPushed(app, event)
            % ────────────────────────────────────────────────────────────────────────
            %  Envia el dato del EditField correspondiente a un valor de calibración
            % ────────────────────────────────────────────────────────────────────────
            try
                str = "#set-cal$" + string(app.ef_valCalManual.Value) + "&";

                if ~utilidades.checkResponseFromSerial(app.serial.connection, str, "#saved-cte-calib&")
                    error("Ocurrió un error al cargar el parametro!");
                end
                
                uialert(app.CeldaCargaFigure, 'Valor de calibracion actualizado correctamente!', 'Descarga', Icon = 'success');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_doTara
        function btn_doTaraButtonPushed(app, event)
            % ───────────────────────────────────────────────────────────────
            %           Envia el comando para realizar la Tara
            % ───────────────────────────────────────────────────────────────
            try
                if ~utilidades.checkResponseFromSerial(app.serial.connection, "#set-tare&", "#tare-ok&")
                    error("Fallo la comunicacion con el módulo. No se logró realizar la Tara!");
                end
                
                uialert(app.CeldaCargaFigure, 'Tara realizada con éxito!', 'Tara', Icon = 'success');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_doCalAuto
        function btn_doCalAutoButtonPushed(app, event)
            % ─────────────────────────────────────────────────────────────────────
            %  Envia el comando para iniciar el proceso de calibracion automatica
            % ─────────────────────────────────────────────────────────────────────
            d = uiprogressdlg(app.CeldaCargaFigure, Message="Iniciando el proceso...", Title="Calibración Automática...", ShowPercentage="off");
           
            try
                % Inicio el proceso
                % ───────────────────> Mensaje del uiprogress
                d.Indeterminate = 'on';
                % ───────────────────────────────────────────] 
                if ~utilidades.checkResponseFromSerial(app.serial.connection, "#do-auto-cal&", "#cal-process&")
                    error("No se recibió el acuse de inicio del proceso de calibración!");
                end
                
                tic;

                while true
                    if app.serial.connection.NumBytesAvailable > 0
                        data = readline(app.serial.connection);

                        % Verifico los mensajes fijos
                        if strcmp(data, "#cal-init&")
                            % ───────────────────> Mensaje del uiprogress
                            d.Indeterminate = 'on';
                            d.Message = "Realizando Calibración...";
                            % ───────────────────────────────────────────]
                        elseif strcmp(data, "#cal-end&")
                            pause(0.1);
                            resp = utilidades.getParamFloat(app.serial.connection, "#get-cte-calib&", "cte-calib");
                            if isnan(resp)
                                error('Ocurrio un error al intentar verificar el valor de la constante obtenida!');
                            end
                            app.ef_valCalManual.Value = resp;
                              
                            break;
                        else
                            porcentaje = utilidades.processRawData(data, 'cal-time');
                            if isnan(porcentaje)
                                error("Ocurrió un error al procesar los datos en la aplicación!");
                            end
                            
                            % ───────────────────> Mensaje del uiprogress
                            d.Value = porcentaje/100.0;
                            d.Message = "Coloca el peso conocido encima de la balanza antes de que se complete la barra de progreso.";
                            d.Indeterminate = 'off';
                            % ───────────────────────────────────────────] 
                        end 
                    end

                    % Con estas dos funciones puedo ver cuanto tarda el proceso completo
                    % para luego fijar el timeout
                    % disp(toc);
                    % pause(0.1);

                    if(toc > app.config.timeout_cal_auto)
                        error("La calibración finalizo con errores, reintenta nuevamente!");
                    end
                end

                uialert(app.CeldaCargaFigure, 'Calibración automática realizada con éxito!', 'Calibración', Icon = 'success');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
            close(d);
            
        end

        % Button pushed function: btn_tomarMuestra
        function btn_tomarMuestraButtonPushed(app, event)
            % ───────────────────────────────────────────────────────────────
            %   Envia la peticion al arduino para leer una muestra única
            % ───────────────────────────────────────────────────────────────
            app.lamp_reading.Color = [0.00,1.00,0.00];  % Lamp en verde
            try
                one_read = utilidades.getParamFloat(app.serial.connection, "#get-one-read&", "one-read");
                if isnan(one_read)
                    error("No se obtuvo una respuesta válida!");
                end
                
                app.lbl_valFuerzaKg.Text = string(one_read) + " kg";
                app.medidor_KG.Value = one_read;
                pause(0.2);
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
            app.lamp_reading.Color = [0.85,0.33,0.10];  % Lamp en naranja
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CeldaCargaFigure = uifigure('Visible', 'off');
            app.CeldaCargaFigure.Name = 'Celda de Carga V1.0';
            app.CeldaCargaFigure.Icon = fullfile(pathToMLAPP, 'assets', 'icon.png');
            app.CeldaCargaFigure.CloseRequestFcn = createCallbackFcn(app, @Close_App, true);
            app.graph_FuerzaKg = uiaxes(app.CeldaCargaFigure);
            xlabel(app.graph_FuerzaKg, 'Tiempo [s]')
            ylabel(app.graph_FuerzaKg, 'Fuerza [kg]')
            zlabel(app.graph_FuerzaKg, 'Z')
            app.btn_stopRecord = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_stopRecord.ButtonPushedFcn = createCallbackFcn(app, @btn_stopRecordButtonPushed, true);
            app.btn_stopRecord.Text = 'Detener registro';
            app.btn_initRecord = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_initRecord.ButtonPushedFcn = createCallbackFcn(app, @btn_initRecordButtonPushed, true);
            app.btn_initRecord.Text = 'Iniciar registro';
            app.lbl_nombreArchivo = uilabel(app.CeldaCargaFigure);
            app.lbl_nombreArchivo.Visible = 'off';
            app.lbl_nombreArchivo.Text = 'xxxxxxxx';
            app.lbl_NombreArchivoTextoFijo = uilabel(app.CeldaCargaFigure);
            app.lbl_NombreArchivoTextoFijo.Visible = 'off';
            app.lbl_NombreArchivoTextoFijo.Text = 'Archivo:';
            app.btn_ExportCSV = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_ExportCSV.ButtonPushedFcn = createCallbackFcn(app, @Export_CSV, true);
            app.btn_ExportCSV.Tooltip = {'El archivo .CSV contiene los parametros de los boxs visibles, y los dos vectores de la grafica.'};
            app.btn_ExportCSV.Text = 'Exportar en Archivo .CSV';
            app.btn_ExportMAT = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_ExportMAT.ButtonPushedFcn = createCallbackFcn(app, @Export_MAT, true);
            app.btn_ExportMAT.Tooltip = {'El archivo .MAT contiene los parametros de los boxs visibles, y los dos vectores de la grafica.'};
            app.btn_ExportMAT.Text = 'Exportar en Archivo .MAT';
            app.btn_abrirArchivoMAT = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_abrirArchivoMAT.ButtonPushedFcn = createCallbackFcn(app, @Import_MAT, true);
            app.btn_abrirArchivoMAT.Text = 'Importar Archivo .MAT';
            app.tab_Ajustes = uitabgroup(app.CeldaCargaFigure);
            app.tab_AjusteCalibracion = uitab(app.tab_Ajustes);
            app.tab_AjusteCalibracion.Title = 'Calibración';
            app.CalibracinoManualLabel = uilabel(app.tab_AjusteCalibracion);
            app.CalibracinoManualLabel.Text = 'Calibración Manual:';
            app.CalibracionAutomticaLabel = uilabel(app.tab_AjusteCalibracion);
            app.CalibracionAutomticaLabel.Text = 'Calibración Automática:';
            app.btn_doCalAuto = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_doCalAuto.ButtonPushedFcn = createCallbackFcn(app, @btn_doCalAutoButtonPushed, true);
            app.btn_doCalAuto.Text = 'Iniciar Calibración';
            app.btn_uploadPesoConocido = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_uploadPesoConocido.ButtonPushedFcn = createCallbackFcn(app, @btn_uploadPesoConocidoButtonPushed, true);
            app.btn_uploadPesoConocido.Text = 'Cargar';
            app.PesoConocidokgLabel = uilabel(app.tab_AjusteCalibracion);
            app.PesoConocidokgLabel.Text = 'Peso Conocido [kg]:';
            app.ef_valPesoConocido = uieditfield(app.tab_AjusteCalibracion, 'numeric');
            app.ef_valPesoConocido.ValueDisplayFormat = '%.2f';
            app.ef_valPesoConocido.Value = 0.1;
            app.btn_doCalManual = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_doCalManual.ButtonPushedFcn = createCallbackFcn(app, @btn_doCalManualButtonPushed, true);
            app.btn_doCalManual.Text = 'Calibrar';
            app.btn_readValCalManual = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_readValCalManual.ButtonPushedFcn = createCallbackFcn(app, @btn_readValCalManualButtonPushed, true);
            app.btn_readValCalManual.Text = 'Leer';
            app.btn_readValPesoConocido = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_readValPesoConocido.ButtonPushedFcn = createCallbackFcn(app, @btn_readValPesoConocidoButtonPushed, true);
            app.btn_readValPesoConocido.Text = 'Leer';
            app.ValordeCalibracinEditFieldLabel = uilabel(app.tab_AjusteCalibracion);
            app.ValordeCalibracinEditFieldLabel.Text = 'Valor de Calibración:';
            app.ef_valCalManual = uieditfield(app.tab_AjusteCalibracion, 'numeric');
            app.ef_valCalManual.ValueDisplayFormat = '%.2f';
            app.ef_valCalManual.Value = 0.1;
            app.tab_TipoConexion = uitabgroup(app.CeldaCargaFigure);
            app.ConexionSerialTab = uitab(app.tab_TipoConexion);
            app.ConexionSerialTab.Title = 'Conexion Serial';
            app.btn_serialConnect = uibutton(app.ConexionSerialTab, 'push');
            app.btn_serialConnect.ButtonPushedFcn = createCallbackFcn(app, @Serial_Connect, true);
            app.btn_serialConnect.Text = 'Conectar';
            app.lbl_serialBPS = uilabel(app.ConexionSerialTab);
            app.lbl_serialBPS.Text = 'Velocidad:';
            app.ef_serialBPS = uieditfield(app.ConexionSerialTab, 'numeric');
            app.ef_serialBPS.ValueDisplayFormat = '%.0f';
            app.ef_serialBPS.Value = 57600;
            app.btn_UpdateSerialPort = uibutton(app.ConexionSerialTab, 'push');
            app.btn_UpdateSerialPort.ButtonPushedFcn = createCallbackFcn(app, @Change_Serial_BPS, true);
            app.btn_UpdateSerialPort.Text = 'Actualizar';
            app.lbl_serialPort = uilabel(app.ConexionSerialTab);
            app.lbl_serialPort.Text = 'Puerto COM:';
            app.dd_serialPort = uidropdown(app.ConexionSerialTab);
            app.img_Logo = uiimage(app.CeldaCargaFigure);
            app.img_Logo.ImageClickedFcn = createCallbackFcn(app, @img_LogoImageClicked, true);
            app.img_Logo.ImageSource = fullfile(pathToMLAPP, 'assets', 'Logo_LEME.png');
            app.btn_imgHelp = uiimage(app.CeldaCargaFigure);
            app.btn_imgHelp.ImageClickedFcn = createCallbackFcn(app, @Click_Help_Window, true);
            app.btn_imgHelp.ImageSource = fullfile(pathToMLAPP, 'assets', 'help-icon.png');
            app.FuerzaLabel = uilabel(app.CeldaCargaFigure);
            app.FuerzaLabel.Text = 'Fuerza:';
            app.lbl_valFuerzaKg = uilabel(app.CeldaCargaFigure);
            app.lbl_valFuerzaKg.Text = '0.00 kg';
            app.medidor_KG = uigauge(app.CeldaCargaFigure, 'circular');
            app.medidor_KG.Limits = [-500 500];
            app.lamp_reading = uilamp(app.CeldaCargaFigure);
            app.btn_doTara = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_doTara.ButtonPushedFcn = createCallbackFcn(app, @btn_doTaraButtonPushed, true);
            app.btn_doTara.Text = 'TARA';
            app.btn_tomarMuestra = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_tomarMuestra.ButtonPushedFcn = createCallbackFcn(app, @btn_tomarMuestraButtonPushed, true);
            app.btn_tomarMuestra.Text = 'Tomar Muestra';
            % Show the figure after all components are created
            app.CeldaCargaFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeldaLeme

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CeldaCargaFigure)

                % Execute the startup function
                runStartupFcn(app, @onLoad)
            else

                % Focus the running singleton app
                figure(runningApp.CeldaCargaFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CeldaCargaFigure)
        end
    end
end